classdef PWM_SIGNAL_GENERATOR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MODULATIONINDEXSlider           matlab.ui.control.Slider
        MODULATIONINDEXSliderLabel      matlab.ui.control.Label
        FREQUENCYOFINPUTWAVESlider      matlab.ui.control.Slider
        FREQUENCYOFINPUTWAVESliderLabel  matlab.ui.control.Label
        FREQUENCYOFCARRIERWAVESlider    matlab.ui.control.Slider
        FREQUENCYOFCARRIERWAVESliderLabel  matlab.ui.control.Label
        ChooseInputWaveformButtonGroup  matlab.ui.container.ButtonGroup
        TRIANGULARWAVEButton            matlab.ui.control.RadioButton
        SINEWAVEButton                  matlab.ui.control.RadioButton
        COSINEWAVEButton                matlab.ui.control.RadioButton
        OUTPUTButton                    matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OUTPUTButton
        function OUTPUTButtonPushed(app, event)

   
   A = app.MODULATIONINDEXSlider.Value; % Modulation Index
f = app.FREQUENCYOFCARRIERWAVESlider.Value;       % Carrier frequency
w = app.FREQUENCYOFINPUTWAVESlider.Value;            % Modulating signal frequency
t = 0:0.0001:10;                     % Time vector

% Generate Sawtooth Carrier Wave
C = sawtooth(f * t);

% Plot Carrier Wave
plot(app.UIAxes3, t, C, 'b');
hold(app.UIAxes3, 'on'); % Allow multiple plots
xlabel(app.UIAxes3, 'Time');
ylabel(app.UIAxes3, 'Amplitude');
title(app.UIAxes3, 'Carrier Wave (Blue)');
legend(app.UIAxes3, 'Carrier Wave (C)', 'Location', 'best');

% Generate Modulating Waveform
if app.SINEWAVEButton.Value
    a = A * sin(w * t);
elseif app.COSINEWAVEButton.Value
    a = A * cos(w * t);
elseif app.TRIANGULARWAVEButton.Value
    a =A*sawtooth(w * t, 0.5); % Triangular wave

else
    uialert(app.UIFigure, 'Select a waveform', 'Warning'); % Show UI alert
    return;
end

% Plot Selected Modulating Waveform
plot(app.UIAxes, t, a, 'r');
xlabel(app.UIAxes, 'Time');
ylabel(app.UIAxes, 'Amplitude');
title(app.UIAxes, 'Selected Waveform (Red)');
legend(app.UIAxes, 'Selected Waveform (a)', 'Location', 'best');

% Overlay Carrier Wave and Modulating Waveform
plot(app.UIAxes3, t, C, 'b', t, a, 'r');
xlabel(app.UIAxes3, 'Time');
ylabel(app.UIAxes3, 'Amplitude');
title(app.UIAxes3, 'Carrier Wave (Blue) vs Selected Waveform (Red)');
legend(app.UIAxes3, {'Carrier Wave (C)', 'Selected Waveform (a)'}, 'Location', 'best');
hold(app.UIAxes3, 'off'); % Stop overlay

% Generate PWM Signal
pwm_signal = (a >= C);

% Plot PWM Signal
plot(app.UIAxes2, t, pwm_signal, 'm');
xlabel(app.UIAxes2, 'Time');
ylabel(app.UIAxes2, 'PWM Signal');
title(app.UIAxes2, 'PWM Signal (Magenta)');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'INPUT WAVEFORM')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PWM')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'CARRIER SAMPLING WAVEFORM')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.OUTPUTButton = uibutton(app.UIFigure, 'push');
            app.OUTPUTButton.ButtonPushedFcn = createCallbackFcn(app, @OUTPUTButtonPushed, true);
            app.OUTPUTButton.Text = 'OUTPUT';
            app.ChooseInputWaveformButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseInputWaveformButtonGroup.Title = 'Choose Input Waveform';
            app.COSINEWAVEButton = uiradiobutton(app.ChooseInputWaveformButtonGroup);
            app.COSINEWAVEButton.Text = 'COSINE WAVE';
            app.COSINEWAVEButton.Value = true;
            app.SINEWAVEButton = uiradiobutton(app.ChooseInputWaveformButtonGroup);
            app.SINEWAVEButton.Text = 'SINE WAVE';
            app.TRIANGULARWAVEButton = uiradiobutton(app.ChooseInputWaveformButtonGroup);
            app.TRIANGULARWAVEButton.Text = 'TRIANGULAR WAVE';
            app.FREQUENCYOFCARRIERWAVESliderLabel = uilabel(app.UIFigure);
            app.FREQUENCYOFCARRIERWAVESliderLabel.Text = 'FREQUENCY OF CARRIER WAVE';
            app.FREQUENCYOFCARRIERWAVESlider = uislider(app.UIFigure);
            app.FREQUENCYOFINPUTWAVESliderLabel = uilabel(app.UIFigure);
            app.FREQUENCYOFINPUTWAVESliderLabel.Text = 'FREQUENCY OF INPUT WAVE';
            app.FREQUENCYOFINPUTWAVESlider = uislider(app.UIFigure);
            app.MODULATIONINDEXSliderLabel = uilabel(app.UIFigure);
            app.MODULATIONINDEXSliderLabel.Text = 'MODULATION INDEX';
            app.MODULATIONINDEXSlider = uislider(app.UIFigure);
            app.MODULATIONINDEXSlider.Limits = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PWM_SIGNAL_GENERATOR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end